classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GenerateFromMusicSheetButton  matlab.ui.control.Button
        PlayGeneratedAudioButton      matlab.ui.control.Button
        PlayOriginalAudioButton       matlab.ui.control.Button
        SoftwareGeneratedLabel        matlab.ui.control.Label
        CreateSongButton              matlab.ui.control.StateButton
        RecordButton                  matlab.ui.control.StateButton
        OriginalLabel                 matlab.ui.control.Label
        fig2                          matlab.ui.control.UIAxes
        fig                           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        recObj = audiorecorder(44100, 16, 1, -1); % Description
        playerObj % Description
        audioData;
        GeneratedSong;
        notes;
        timings;
        len_x;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RecordButton
        function RecordButtonValueChanged(app, event)
            if app.RecordButton.Value == 1
                % Start recording
                disp("Begin recording.")
                app.RecordButton.Text = 'Stop';
                app.RecordButton.BackgroundColor = 'Red';
                record(app.recObj);
                

            else
                % Stop recording
                disp("End of recording.")
                app.RecordButton.Value = 0;                
                app.RecordButton.Text = 'Start';
                app.RecordButton.BackgroundColor = 'Green';
                stop(app.recObj);

                % Create a new player object with the new recorded data
                app.playerObj = audioplayer(getaudiodata(app.recObj), app.recObj.SampleRate);
                app.audioData = getaudiodata(app.recObj);
                

                % Plot the audio on the specified axes
                Fs = 44100;
                %cla(app.fig);
                original_time = (0:length(app.audioData)-1)/Fs;
                plot(app.fig, original_time, app.audioData);
                grid(app.fig, 'on');
                axis(app.fig, [min(original_time), max(original_time), min(app.audioData), max(app.audioData)]);

            end            
        end

        % Value changed function: CreateSongButton
        function CreateSongButtonValueChanged(app, event)
                original_time = (0:length(app.audioData)-1)/44100;
                % Create a new player object with the new recorded data
                app.playerObj = audioplayer(getaudiodata(app.recObj), app.recObj.SampleRate);
                app.audioData = getaudiodata(app.recObj);
                [app.notes, app.timings] = audioProcessing(app.audioData);
                app.GeneratedSong = songPlayer(app.notes, app.timings, app.fig2, max(original_time));
        end

        % Button pushed function: PlayOriginalAudioButton
        function PlayOriginalAudioButtonPushed(app, event)
            play(app.playerObj);
        end

        % Button pushed function: PlayGeneratedAudioButton
        function PlayGeneratedAudioButtonPushed(app, event)
            sound(app.GeneratedSong, 44100);
        end

        % Button pushed function: GenerateFromMusicSheetButton
        function GenerateFromMusicSheetButtonPushed(app, event)
            % Open the file for reading
            % Open the file for reading
            fileID = fopen('MusicSheet.txt', 'r');
            
            % Initialize arrays
            loc = [];
            app.notes = {};
            
            % Read data from the file
            while ~feof(fileID)
                % Read data from the current line
                currentLine = fgetl(fileID);
                
                % Split the line into two parts using tab as a delimiter
                parts = strsplit(currentLine, '\t');
                
                % Convert the first part to a numeric value and add it to loc array
                locValue = str2double(parts{1});
                loc = [loc locValue];
                
                % Add the second part (notes) to the notes cell array
                notesValue = strtrim(parts{2}); % Remove leading/trailing whitespaces
                app.notes = [app.notes notesValue];
            end
            
            % Close the file
            fclose(fileID);
            original_time = (0:length(app.audioData)-1)/44100;
            app.GeneratedSong = songPlayer(app.notes, app.timings, app.fig2, max(original_time));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.fig = uiaxes(app.UIFigure);
            xlabel(app.fig, 'X')
            ylabel(app.fig, 'Y')
            zlabel(app.fig, 'Z')
            app.fig2 = uiaxes(app.UIFigure);
            xlabel(app.fig2, 'X')
            ylabel(app.fig2, 'Y')
            zlabel(app.fig2, 'Z')
            app.OriginalLabel = uilabel(app.UIFigure);
            app.OriginalLabel.Text = 'Original';
            app.RecordButton = uibutton(app.UIFigure, 'state');
            app.RecordButton.ValueChangedFcn = createCallbackFcn(app, @RecordButtonValueChanged, true);
            app.RecordButton.Text = 'Start Recording';
            app.CreateSongButton = uibutton(app.UIFigure, 'state');
            app.CreateSongButton.ValueChangedFcn = createCallbackFcn(app, @CreateSongButtonValueChanged, true);
            app.CreateSongButton.Text = 'Create';
            app.SoftwareGeneratedLabel = uilabel(app.UIFigure);
            app.SoftwareGeneratedLabel.Text = {'Software'; 'Generated'};
            app.PlayOriginalAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayOriginalAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalAudioButtonPushed, true);
            app.PlayOriginalAudioButton.Text = 'Play';
            app.PlayGeneratedAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayGeneratedAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayGeneratedAudioButtonPushed, true);
            app.PlayGeneratedAudioButton.Text = 'Play';
            app.GenerateFromMusicSheetButton = uibutton(app.UIFigure, 'push');
            app.GenerateFromMusicSheetButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFromMusicSheetButtonPushed, true);
            app.GenerateFromMusicSheetButton.Text = 'Generate From Music Sheet';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end